classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RLCButton                       matlab.ui.control.Button
        FileMemoryEditField             matlab.ui.control.NumericEditField
        FileMemoryEditFieldLabel        matlab.ui.control.Label
        RLCimageLabel                   matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        CompressionratioEditFieldLabel  matlab.ui.control.Label
        CompressionratioEditField       matlab.ui.control.NumericEditField
        ImageMemoryEditFieldLabel       matlab.ui.control.Label
        ImageMemoryEditField            matlab.ui.control.NumericEditField
        UploadButton                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           global I;
           global fullname;
           [filename,filepath] = uigetfile({'*.*'}, 'Select File to Open');
           fullname = [filepath, filename];
           I = imread(fullname);
           imshow(I,'Parent',app.UIAxes);
           %imagesc(app.UIAxes, ImageFile);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
 
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Value changed function: ImageMemoryEditField
        function ImageMemoryEditFieldValueChanged(app, event)
 
            
        end

        % Button pushed function: RLCButton
        function RLCButtonPushed(app, event)
           global I;
           global fullname;
           file = fopen('cameraman.txt', 'w');
           [r,c]=size(I);
           pixels = r * c;
           fwrite(file, c, 'uint16');
           fwrite(file, r, 'uint16');
           ptr = 1;
           count = 0;
           val = I(1, 1);
           imgRow = reshape(I, 1, pixels);
           data = uint8(zeros(1, 2*pixels));
           for i = 1 : pixels
               if(imgRow(1,i) == val)
                   count = count + 1;
               else
                   data(1,ptr) = uint8(val);
                   data(1,ptr+1) = uint8(count);
                   ptr = ptr + 2;
                   val = imgRow(1,i);   
                   count = 1; 
               end
           end
           data(1,ptr) = uint8(val);
           data(1,ptr+1) = uint8(count);
           data = data(1,1:(ptr+1));
           fwrite(file, data, 'uint8');
           fclose(file);

           % Decoding
           file1 = fopen('cameraman.txt', 'r');
           c1=fread(file1, 1, 'uint16');
           r1=fread(file1, 1, 'uint16');
           ptr1 = 1;
           pixels1=r1*c1;
           imgRow1 = uint8(zeros(1, pixels1));
           data1 = fread(file1, [1 inf], 'uint8');
           for i = 1 : 2 : length(data1)   
               val1 = data1(1,i);  
               count1 = data1(1,i+1);  
               imgRow1(1,ptr1:(ptr1+count1-1)) = val1 * ones(1,count1);   
               ptr1 = ptr1 + count1;
           end
           img1 = reshape(imgRow1, c1, r1);
           fclose(file1);
           imshow(img1,'Parent',app.UIAxes_2);
           in = imfinfo(fullname);
           S1=8*r*c;
           props=dir("cameraman.txt");
           v1=props.bytes;
           MemoryCompression_2=(in.Width*in.Height*in.BitDepth/8)/v1;
           app.CompressionratioEditField.Value = MemoryCompression_2;
           app.FileMemoryEditField.Value = v1;
           app.ImageMemoryEditField.Value = S1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ImageMemoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageMemoryEditField.ValueChangedFcn = createCallbackFcn(app, @ImageMemoryEditFieldValueChanged, true);
            app.ImageMemoryEditFieldLabel = uilabel(app.UIFigure);
            app.ImageMemoryEditFieldLabel.Text = 'Image Memory';
            app.CompressionratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompressionratioEditFieldLabel = uilabel(app.UIFigure);
            app.CompressionratioEditFieldLabel.Text = 'Compression ratio';
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.RLCimageLabel = uilabel(app.UIFigure);
            app.RLCimageLabel.Text = 'RLC image';
            app.FileMemoryEditFieldLabel = uilabel(app.UIFigure);
            app.FileMemoryEditFieldLabel.Text = 'File Memory';
            app.FileMemoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.RLCButton = uibutton(app.UIFigure, 'push');
            app.RLCButton.ButtonPushedFcn = createCallbackFcn(app, @RLCButtonPushed, true);
            app.RLCButton.Text = 'RLC ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end